classdef ctrlpanel < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        CytonControlPanelLabel       matlab.ui.control.Label
        SurfacePreparationsPanel     matlab.ui.container.Panel
        PanelDimensionsLabel         matlab.ui.control.Label
        WidthmEditFieldLabel         matlab.ui.control.Label
        WidthEditField               matlab.ui.control.NumericEditField
        HeightmEditFieldLabel        matlab.ui.control.Label
        HeightEditField              matlab.ui.control.NumericEditField
        StartingPositionLabel        matlab.ui.control.Label
        PositionDropDownLabel        matlab.ui.control.Label
        PositionDropDown             matlab.ui.control.DropDown
        SetDimensions                matlab.ui.control.Button
        SetPosition                  matlab.ui.control.Button
        JointAngleControlPanel       matlab.ui.container.Panel
        J1SliderLabel                matlab.ui.control.Label
        J1Slider                     matlab.ui.control.Slider
        J2SliderLabel                matlab.ui.control.Label
        J2Slider                     matlab.ui.control.Slider
        J3SliderLabel                matlab.ui.control.Label
        J3Slider                     matlab.ui.control.Slider
        J4SliderLabel                matlab.ui.control.Label
        J4Slider                     matlab.ui.control.Slider
        J5SliderLabel                matlab.ui.control.Label
        J5Slider                     matlab.ui.control.Slider
        J6SliderLabel                matlab.ui.control.Label
        J6Slider                     matlab.ui.control.Slider
        J7SliderLabel                matlab.ui.control.Label
        J7Slider                     matlab.ui.control.Slider
        Joint_1                      matlab.ui.control.NumericEditField
        Joint_2                      matlab.ui.control.NumericEditField
        Joint_3                      matlab.ui.control.NumericEditField
        Joint_4                      matlab.ui.control.NumericEditField
        Joint_5                      matlab.ui.control.NumericEditField
        Joint_6                      matlab.ui.control.NumericEditField
        Joint_7                      matlab.ui.control.NumericEditField
        HOMEButton                   matlab.ui.control.Button
        RobotStatusPanel             matlab.ui.container.Panel
        CollisionDetectionLampLabel  matlab.ui.control.Label
        CollisionDetectionLamp       matlab.ui.control.Lamp
        LightGateLampLabel           matlab.ui.control.Label
        LightGateLamp                matlab.ui.control.Lamp
        RobotMotionLampLabel         matlab.ui.control.Label
        RobotMotionLamp              matlab.ui.control.Lamp
        RESETButton                  matlab.ui.control.Button
        XEditFieldLabel              matlab.ui.control.Label
        XEditField                   matlab.ui.control.NumericEditField
        YEditFieldLabel              matlab.ui.control.Label
        YEditField                   matlab.ui.control.NumericEditField
        ZEditFieldLabel              matlab.ui.control.Label
        ZEditField                   matlab.ui.control.NumericEditField
        RollEditFieldLabel           matlab.ui.control.Label
        RollEditField                matlab.ui.control.NumericEditField
        PitchEditFieldLabel          matlab.ui.control.Label
        PitchEditField               matlab.ui.control.NumericEditField
        YawEditFieldLabel            matlab.ui.control.Label
        YawEditField                 matlab.ui.control.NumericEditField
        RobotPositionLabel           matlab.ui.control.Label
        EmptyPanel                   matlab.ui.container.Panel
        TabGroup                     matlab.ui.container.TabGroup
        Tab                          matlab.ui.container.Tab
        Tab2                         matlab.ui.container.Tab
        STOPButton                   matlab.ui.control.Button
        STARTButton                  matlab.ui.control.Button
        movingButton                 matlab.ui.control.Button
    end

    % Properties that link up other functions/variables from other MATLAB scripts
    properties (Access = public)
        Cereallink
        cytonbot
        robotClient
        robotMsg
        torqueInit
        goHome
        cute_enable_robot_client
        cute_enable_robot_msg
        cute_home_client
        cute_home_client_msg
        cute_position_publisher
        cute_position_msg
        irFlag;
    end

    methods (Access = public)
        % Function used to update the position of the end-effector in each respective text box
        function updateXYZRPY(app)
            [a,b] = app.Cereallink.updateEndEffectorPos();
            app.XEditField.Value = a(1,4);
            app.YEditField.Value = a(2,4);
            app.ZEditField.Value = a(3,4);
            app.RollEditField.Value = b(1,1);
            app.PitchEditField.Value = b(1,2);
            app.YawEditField.Value = b(1,3);
        end
        % Main function used for robot motion - includes trajectory algorithms and robot status updates      
        function moveTRAJsimulation(app,qMatrix,steps)
            app.RobotMotionLamp.Color = [0 1 0];
            for i = 1:steps
                if app.Cereallink.estopflag ~= 0
                    while app.Cereallink.estopflag ~= 0
                        pause(0.1);
                    end
                end
                app.irduino();
%           pos = qMatrix(i,:);
%           app.cute_position_msg.Data = [pos];
%           app.cute_position_publisher.send(app.cute_position_msg);
            if app.Cereallink.IsCollision(qMatrix(i,:), app.Cereallink.faces, app.Cereallink.vertex, app.Cereallink.faceNormals)
                display('Collision detected!!');
                app.CollisionDetectionLamp.Color = [1 0 0];
            else
                display('No collision found');
                app.CollisionDetectionLamp.Color = [0 1 0];
            end            
            app.Cereallink.sim_cytonanimate(qMatrix(i,:));
            app.updateXYZRPY()
            
%             a = app.Cereallink.getCurrentPos();
%             a = rad2deg(a);
%             app.Joint_1.Value = a(1,1);
%             app.Joint_2.Value = a(1,2);
%             app.Joint_3.Value = a(1,3);
%             app.Joint_4.Value = a(1,4);
%             app.Joint_5.Value = a(1,5);
%             app.Joint_6.Value = a(1,6);
%             app.Joint_7.Value = a(1,7);
            end
            app.RobotMotionLamp.Color = [1 0 0];
        end 
    
        % 
        function moveRMRCsimulation(app,qMatrix,steps)
            app.RobotMotionLamp.Color = [0 1 0];
            for i = 1:steps
                if app.Cereallink.estopflag ~= 0
                    while app.Cereallink.estopflag ~= 0
                        pause(0.1);
                    end
                end
                app.irduino();
%           pos = qMatrix(i,:);
%           app.cute_position_msg.Data = [pos];
%           app.cute_position_publisher.send(app.cute_position_msg);
            if app.Cereallink.IsCollision(qMatrix(i,:), app.Cereallink.faces, app.Cereallink.vertex, app.Cereallink.faceNormals)
                display('Collision detected!!');
                app.CollisionDetectionLamp.Color = [1 0 0];
%                 qCurrent = app.Cereallink.getCurrentPos();
%                 collisionLoc = app.Cereallink.getfkine(qCurrent);
%                 reroute = collisionLoc * transl(0.05,0,0);
%                 newq = app.Cereallink.getikcon(reroute);
%                 app.Cereallink.sim_cytonanimate(newq);
            else
                display('No collision found');
                app.CollisionDetectionLamp.Color = [0 1 0];
            end
            app.Cereallink.sim_cytonanimate(qMatrix(i,:));
            app.updateXYZRPY()
            
%             a = app.Cereallink.getCurrentPos();
%             a = rad2deg(a);
%             app.Joint_1.Value = a(1,1);
%             app.Joint_2.Value = a(1,2);
%             app.Joint_3.Value = a(1,3);
%             app.Joint_4.Value = a(1,4);
%             app.Joint_5.Value = a(1,5);
%             app.Joint_6.Value = a(1,6);
%             app.Joint_7.Value = a(1,7);
            
            end
            app.RobotMotionLamp.Color = [1 0 0];
        end
    
        function RMRCsetup(app,nextlocation)
            steps = 25;
            [startTransl,endTransl,startRPY,endRPY] = app.Cereallink.updateLocs(nextlocation);
            b = app.Cereallink.RMRC(startTransl,endTransl,startRPY,endRPY,steps);
            app.moveRMRCsimulation(b,steps);
        end
    
        function TRAJsetup(app,nextlocation)
            steps = 25;
            q = app.Cereallink.getendpos(nextlocation);
            a = app.Cereallink.MoveTo(q);
            app.moveTRAJsimulation(a,steps); 
        end

        function irduino(app)           
            a = arduino();
            volts = readVoltage(a,'A0')*0.0048828125;
            Distance = 27.86*(volts.^-1)/100;
            pause(0.01)
            if Distance <= 35
                display('poopoo')
                while Distance <= 35
                    pause(0.1);
                    volts = readVoltage(a,'A0')*0.0048828125;
                    Distance = 27.86*(volts.^-1)/100
                    app.RobotMotionLamp.Color = [1 0 0];
                end
                app.RobotMotionLamp.Color = [0 1 0];
            end
        end
    end


    methods (Access = private)

        % Button pushed function: STARTButton
        function startButton(app, event)
            clf;
            app.Cereallink = a2main;
%%            All code below up until the 'end' must be un-commented to use the GUI functionality properly

%             app.cute_enable_robot_client = rossvcclient('enableCyton');
%             
%             pause(1);
%             app.cute_enable_robot_msg = rosmessage(app.cute_enable_robot_client);
% 
%             pause(1);
%             app.cute_enable_robot_msg.TorqueEnable = true;
%            
%             app.cute_enable_robot_client.call(app.cute_enable_robot_msg);
%             pause(1);            
%             app.cute_home_client = rossvcclient('/goHome');
% 
%             app.cute_home_client_msg = rosmessage(app.cute_home_client);            
%             app.cute_home_client.call(app.cute_home_client_msg);
%             pause(1);
%             app.cute_position_publisher = rospublisher('/cyton_position_commands');
%             app.cute_position_msg = rosmessage(app.cute_position_publisher);
            
        end

        % Value changed function: J1Slider
        function Slider1toJoint1(app, event)
            app.Joint_1.Value = app.J1Slider.Value;
            qNew = app.Cereallink.updateJoint(1,app.J1Slider.Value);
            app.Cereallink.sim_cytonanimate(qNew);
            app.updateXYZRPY()
        end

        % Value changed function: J2Slider
        function Slider2toJoint2(app, event)
           app.Joint_2.Value = app.J2Slider.Value;
           qNew = app.Cereallink.updateJoint(2,app.J2Slider.Value);
           app.Cereallink.sim_cytonanimate(qNew);
           app.updateXYZRPY()
        end

        % Value changed function: J3Slider
        function Slider3toJoint3(app, event)
            app.Joint_3.Value = app.J3Slider.Value;
            qNew = app.Cereallink.updateJoint(3,app.J3Slider.Value);
            app.Cereallink.sim_cytonanimate(qNew);
            app.updateXYZRPY()
        end

        % Value changed function: J4Slider
        function Slider4toJoint4(app, event)
            app.Joint_4.Value = app.J4Slider.Value;
            qNew = app.Cereallink.updateJoint(4,app.J4Slider.Value);
            app.Cereallink.sim_cytonanimate(qNew);
            app.updateXYZRPY()
        end

        % Value changed function: J5Slider
        function Slider5toJoint5(app, event)
            app.Joint_5.Value = app.J5Slider.Value;
            qNew = app.Cereallink.updateJoint(5,app.J5Slider.Value);
            app.Cereallink.sim_cytonanimate(qNew);
            app.updateXYZRPY()
        end

        % Value changed function: J6Slider
        function Slider6toJoint6(app, event)
            app.Joint_6.Value = app.J6Slider.Value;
            qNew = app.Cereallink.updateJoint(6,app.J6Slider.Value);
            app.Cereallink.sim_cytonanimate(qNew);
            app.updateXYZRPY()
        end

        % Value changed function: J7Slider
        function Slider7toJoint7(app, event)
            app.Joint_7.Value = app.J7Slider.Value;
            qNew = app.Cereallink.updateJoint(7,app.J7Slider.Value);
            app.Cereallink.sim_cytonanimate(qNew);
            app.updateXYZRPY()
        end

        % Value changed function: Joint_1
        function Joint1toSlider1(app, event)
            app.J1Slider.Value = app.Joint_1.Value;
            qNew = app.Cereallink.updateJoint(1,app.Joint_1.Value);
            app.Cereallink.sim_cytonanimate(qNew);
            app.updateXYZRPY()
        end

        % Value changed function: Joint_2
        function Joint2toSlider2(app, event)
            app.J2Slider.Value = app.Joint_2.Value;
            qNew = app.Cereallink.updateJoint(2,app.Joint_2.Value);
            app.Cereallink.sim_cytonanimate(qNew);
            app.updateXYZRPY()
        end

        % Value changed function: Joint_3
        function Joint3toSlider3(app, event)
            app.J3Slider.Value = app.Joint_3.Value;
            qNew = app.Cereallink.updateJoint(3,app.Joint_3.Value);
            app.Cereallink.sim_cytonanimate(qNew);
            app.updateXYZRPY()
        end

        % Value changed function: Joint_4
        function Joint4toSlider4(app, event)
            app.J4Slider.Value = app.Joint_4.Value;
            qNew = app.Cereallink.updateJoint(4,app.Joint_4.Value);
            app.Cereallink.sim_cytonanimate(qNew);
            app.updateXYZRPY()
        end

        % Value changed function: Joint_5
        function Joint5toSlider5(app, event)
            app.J5Slider.Value = app.Joint_5.Value;
            qNew = app.Cereallink.updateJoint(5,app.Joint_5.Value);
            app.Cereallink.sim_cytonanimate(qNew);
            app.updateXYZRPY()
        end

        % Value changed function: Joint_6
        function Joint6toSlider6(app, event)
            app.J6Slider.Value = app.Joint_6.Value;
            qNew = app.Cereallink.updateJoint(6,app.Joint_6.Value);
            app.Cereallink.sim_cytonanimate(qNew);
            app.updateXYZRPY()
        end

        % Value changed function: Joint_7
        function Joint7toSlider7(app, event)
            app.J7Slider.Value = app.Joint_7.Value;
            qNew = app.Cereallink.updateJoint(7,app.Joint_7.Value);
            app.Cereallink.sim_cytonanimate(qNew);
            app.updateXYZRPY()
        end

        % Button pushed function: HOMEButton
        function HOMEButtonPushed(app, event)
            app.J1Slider.Value = 0;
            app.J2Slider.Value = 0;
            app.J3Slider.Value = 0;
            app.J4Slider.Value = 0;
            app.J5Slider.Value = 0;
            app.J6Slider.Value = 0;
            app.J7Slider.Value = 0;
            app.Joint_1.Value = 0;
            app.Joint_2.Value = 0;
            app.Joint_3.Value = 0;
            app.Joint_4.Value = 0;
            app.Joint_5.Value = 0;
            app.Joint_6.Value = 0;
            app.Joint_7.Value = 0;
            app.Cereallink.updateJoint(1,app.Joint_1.Value);
            app.Cereallink.updateJoint(2,app.Joint_2.Value);
            app.Cereallink.updateJoint(3,app.Joint_3.Value);
            app.Cereallink.updateJoint(4,app.Joint_4.Value);
            app.Cereallink.updateJoint(5,app.Joint_5.Value);
            app.Cereallink.updateJoint(6,app.Joint_6.Value);
            app.Cereallink.updateJoint(7,app.Joint_7.Value);
            app.updateXYZRPY()
            qhome = [0 0 0 0 0 0 0];
            app.Cereallink.sim_cytonanimate(qhome);
%            app.cute_home_client_msg = rosmessage(app.cute_home_client);            
%            app.cute_home_client.call(app.cute_home_client_msg);
        end

        % Button pushed function: movingButton
        function movingButtonPushed(app, event)
            app.Cereallink.estopflag = 0;
            
            
            app.TRAJsetup(app.Cereallink.T1ShowerLoc);
            app.RMRCsetup(app.Cereallink.T2ShowerLoc);
            app.RMRCsetup(app.Cereallink.T3ShowerLoc);
            app.RMRCsetup(app.Cereallink.T4ShowerLoc);
            app.RMRCsetup(app.Cereallink.T5ShowerLoc);
            
            app.TRAJsetup(app.Cereallink.waypoint1);
%             app.TRAJsetup(app.Cereallink.waypoint2);
            app.TRAJsetup(app.Cereallink.waypoint3);
            
            app.RMRCsetup(app.Cereallink.M1ShowerLoc);
            app.RMRCsetup(app.Cereallink.M2ShowerLoc);
            app.RMRCsetup(app.Cereallink.M3ShowerLoc);
            app.RMRCsetup(app.Cereallink.M4ShowerLoc);
            app.RMRCsetup(app.Cereallink.M5ShowerLoc);
%             app.RobotMotionLamp.Color = [1 0 0];
        end

        % Button pushed function: STOPButton
        function STOPButtonPushed(app, event)
            if app.Cereallink.estopflag == 0
                app.Cereallink.estopflag = 1;
                app.RobotMotionLamp.Color = [1 0 0];
            else
                app.Cereallink.estopflag = 0;
                app.RobotMotionLamp.Color = [0 1 0];
            end
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.CytonControlPanelLabel = uilabel(app.UIFigure);
            app.CytonControlPanelLabel.Text = 'Cyton Control Panel';
            app.SurfacePreparationsPanel = uipanel(app.UIFigure);
            app.SurfacePreparationsPanel.Title = 'Surface Preparations';
            app.PanelDimensionsLabel = uilabel(app.SurfacePreparationsPanel);
            app.PanelDimensionsLabel.Text = 'Panel Dimensions';
            app.WidthmEditFieldLabel = uilabel(app.SurfacePreparationsPanel);
            app.WidthmEditFieldLabel.Text = 'Width (m)';
            app.WidthEditField = uieditfield(app.SurfacePreparationsPanel, 'numeric');
            app.WidthEditField.Limits = [0 500];
            app.WidthEditField.ValueDisplayFormat = '%.2f';
            app.HeightmEditFieldLabel = uilabel(app.SurfacePreparationsPanel);
            app.HeightmEditFieldLabel.Text = 'Height (m)';
            app.HeightEditField = uieditfield(app.SurfacePreparationsPanel, 'numeric');
            app.HeightEditField.Limits = [0 300];
            app.HeightEditField.ValueDisplayFormat = '%.2f';
            app.StartingPositionLabel = uilabel(app.SurfacePreparationsPanel);
            app.StartingPositionLabel.Text = 'Starting Position';
            app.PositionDropDownLabel = uilabel(app.SurfacePreparationsPanel);
            app.PositionDropDownLabel.Text = 'Position';
            app.PositionDropDown = uidropdown(app.SurfacePreparationsPanel);
            app.PositionDropDown.Items = {'Top Left', 'Top Right', 'Bottom Left', 'Bottom Right'};
            app.PositionDropDown.Value = 'Top Left';
            app.SetDimensions = uibutton(app.SurfacePreparationsPanel, 'push');
            app.SetDimensions.Text = 'SET';
            app.SetPosition = uibutton(app.SurfacePreparationsPanel, 'push');
            app.SetPosition.Text = 'SET';
            app.JointAngleControlPanel = uipanel(app.UIFigure);
            app.JointAngleControlPanel.Title = 'Joint Angle Control';
            app.J1SliderLabel = uilabel(app.JointAngleControlPanel);
            app.J1SliderLabel.Text = 'J1';
            app.J1Slider = uislider(app.JointAngleControlPanel);
            app.J1Slider.Limits = [-150 150];
            app.J1Slider.MajorTicks = [];
            app.J1Slider.MajorTickLabels = {};
            app.J1Slider.ValueChangedFcn = createCallbackFcn(app, @Slider1toJoint1, true);
            app.J1Slider.MinorTicks = [];
            app.J2SliderLabel = uilabel(app.JointAngleControlPanel);
            app.J2SliderLabel.Text = 'J2';
            app.J2Slider = uislider(app.JointAngleControlPanel);
            app.J2Slider.Limits = [-105 105];
            app.J2Slider.MajorTicks = [];
            app.J2Slider.MajorTickLabels = {};
            app.J2Slider.ValueChangedFcn = createCallbackFcn(app, @Slider2toJoint2, true);
            app.J2Slider.MinorTicks = [];
            app.J3SliderLabel = uilabel(app.JointAngleControlPanel);
            app.J3SliderLabel.Text = 'J3';
            app.J3Slider = uislider(app.JointAngleControlPanel);
            app.J3Slider.Limits = [-150 150];
            app.J3Slider.MajorTicks = [];
            app.J3Slider.MajorTickLabels = {};
            app.J3Slider.ValueChangedFcn = createCallbackFcn(app, @Slider3toJoint3, true);
            app.J3Slider.MinorTicks = [];
            app.J4SliderLabel = uilabel(app.JointAngleControlPanel);
            app.J4SliderLabel.Text = 'J4';
            app.J4Slider = uislider(app.JointAngleControlPanel);
            app.J4Slider.Limits = [-105 105];
            app.J4Slider.MajorTicks = [];
            app.J4Slider.MajorTickLabels = {};
            app.J4Slider.ValueChangedFcn = createCallbackFcn(app, @Slider4toJoint4, true);
            app.J4Slider.MinorTicks = [];
            app.J5SliderLabel = uilabel(app.JointAngleControlPanel);
            app.J5SliderLabel.Text = 'J5';
            app.J5Slider = uislider(app.JointAngleControlPanel);
            app.J5Slider.Limits = [-105 105];
            app.J5Slider.MajorTicks = [];
            app.J5Slider.MajorTickLabels = {};
            app.J5Slider.ValueChangedFcn = createCallbackFcn(app, @Slider5toJoint5, true);
            app.J5Slider.MinorTicks = [];
            app.J6SliderLabel = uilabel(app.JointAngleControlPanel);
            app.J6SliderLabel.Text = 'J6';
            app.J6Slider = uislider(app.JointAngleControlPanel);
            app.J6Slider.Limits = [-105 105];
            app.J6Slider.MajorTicks = [];
            app.J6Slider.MajorTickLabels = {};
            app.J6Slider.ValueChangedFcn = createCallbackFcn(app, @Slider6toJoint6, true);
            app.J6Slider.MinorTicks = [];
            app.J7SliderLabel = uilabel(app.JointAngleControlPanel);
            app.J7SliderLabel.Text = 'J7';
            app.J7Slider = uislider(app.JointAngleControlPanel);
            app.J7Slider.Limits = [-150 150];
            app.J7Slider.MajorTicks = [];
            app.J7Slider.MajorTickLabels = {};
            app.J7Slider.ValueChangedFcn = createCallbackFcn(app, @Slider7toJoint7, true);
            app.J7Slider.MinorTicks = [];
            app.Joint_1 = uieditfield(app.JointAngleControlPanel, 'numeric');
            app.Joint_1.Limits = [-150 150];
            app.Joint_1.ValueDisplayFormat = '%.3f';
            app.Joint_1.ValueChangedFcn = createCallbackFcn(app, @Joint1toSlider1, true);
            app.Joint_2 = uieditfield(app.JointAngleControlPanel, 'numeric');
            app.Joint_2.Limits = [-105 105];
            app.Joint_2.ValueDisplayFormat = '%.3f';
            app.Joint_2.ValueChangedFcn = createCallbackFcn(app, @Joint2toSlider2, true);
            app.Joint_3 = uieditfield(app.JointAngleControlPanel, 'numeric');
            app.Joint_3.Limits = [-150 150];
            app.Joint_3.ValueDisplayFormat = '%.3f';
            app.Joint_3.ValueChangedFcn = createCallbackFcn(app, @Joint3toSlider3, true);
            app.Joint_4 = uieditfield(app.JointAngleControlPanel, 'numeric');
            app.Joint_4.Limits = [-105 105];
            app.Joint_4.ValueDisplayFormat = '%.3f';
            app.Joint_4.ValueChangedFcn = createCallbackFcn(app, @Joint4toSlider4, true);
            app.Joint_5 = uieditfield(app.JointAngleControlPanel, 'numeric');
            app.Joint_5.Limits = [-105 105];
            app.Joint_5.ValueDisplayFormat = '%.3f';
            app.Joint_5.ValueChangedFcn = createCallbackFcn(app, @Joint5toSlider5, true);
            app.Joint_6 = uieditfield(app.JointAngleControlPanel, 'numeric');
            app.Joint_6.Limits = [-105 105];
            app.Joint_6.ValueDisplayFormat = '%.3f';
            app.Joint_6.ValueChangedFcn = createCallbackFcn(app, @Joint6toSlider6, true);
            app.Joint_7 = uieditfield(app.JointAngleControlPanel, 'numeric');
            app.Joint_7.Limits = [-150 150];
            app.Joint_7.ValueDisplayFormat = '%.3f';
            app.Joint_7.ValueChangedFcn = createCallbackFcn(app, @Joint7toSlider7, true);
            app.HOMEButton = uibutton(app.JointAngleControlPanel, 'push');
            app.HOMEButton.ButtonPushedFcn = createCallbackFcn(app, @HOMEButtonPushed, true);
            app.HOMEButton.Text = 'HOME';
            app.RobotStatusPanel = uipanel(app.UIFigure);
            app.RobotStatusPanel.Title = 'Robot Status';
            app.CollisionDetectionLampLabel = uilabel(app.RobotStatusPanel);
            app.CollisionDetectionLampLabel.Text = 'Collision Detection:';
            app.CollisionDetectionLamp = uilamp(app.RobotStatusPanel);
            app.LightGateLampLabel = uilabel(app.RobotStatusPanel);
            app.LightGateLampLabel.Text = 'Light Gate:';
            app.LightGateLamp = uilamp(app.RobotStatusPanel);
            app.RobotMotionLampLabel = uilabel(app.RobotStatusPanel);
            app.RobotMotionLampLabel.Text = 'Robot Motion:';
            app.RobotMotionLamp = uilamp(app.RobotStatusPanel);
            app.RESETButton = uibutton(app.RobotStatusPanel, 'push');
            app.RESETButton.Text = 'RESET';
            app.XEditFieldLabel = uilabel(app.RobotStatusPanel);
            app.XEditFieldLabel.Text = 'X';
            app.XEditField = uieditfield(app.RobotStatusPanel, 'numeric');
            app.XEditField.ValueDisplayFormat = '%.3f';
            app.XEditField.Editable = 'off';
            app.YEditFieldLabel = uilabel(app.RobotStatusPanel);
            app.YEditFieldLabel.Text = 'Y';
            app.YEditField = uieditfield(app.RobotStatusPanel, 'numeric');
            app.YEditField.ValueDisplayFormat = '%.3f';
            app.YEditField.Editable = 'off';
            app.ZEditFieldLabel = uilabel(app.RobotStatusPanel);
            app.ZEditFieldLabel.Text = 'Z';
            app.ZEditField = uieditfield(app.RobotStatusPanel, 'numeric');
            app.ZEditField.ValueDisplayFormat = '%.3f';
            app.ZEditField.Editable = 'off';
            app.RollEditFieldLabel = uilabel(app.RobotStatusPanel);
            app.RollEditFieldLabel.Text = 'Roll:';
            app.RollEditField = uieditfield(app.RobotStatusPanel, 'numeric');
            app.RollEditField.ValueDisplayFormat = '%.3f';
            app.RollEditField.Editable = 'off';
            app.PitchEditFieldLabel = uilabel(app.RobotStatusPanel);
            app.PitchEditFieldLabel.Text = 'Pitch:';
            app.PitchEditField = uieditfield(app.RobotStatusPanel, 'numeric');
            app.PitchEditField.ValueDisplayFormat = '%.3f';
            app.PitchEditField.Editable = 'off';
            app.YawEditFieldLabel = uilabel(app.RobotStatusPanel);
            app.YawEditFieldLabel.Text = 'Yaw:';
            app.YawEditField = uieditfield(app.RobotStatusPanel, 'numeric');
            app.YawEditField.ValueDisplayFormat = '%.3f';
            app.YawEditField.Editable = 'off';
            app.RobotPositionLabel = uilabel(app.RobotStatusPanel);
            app.RobotPositionLabel.Text = 'Robot Position';
            app.EmptyPanel = uipanel(app.UIFigure);
            app.EmptyPanel.Title = 'Empty';
            app.TabGroup = uitabgroup(app.EmptyPanel);
            app.Tab = uitab(app.TabGroup);
            app.Tab.Title = 'Tab';
            app.Tab2 = uitab(app.TabGroup);
            app.Tab2.Title = 'Tab2';
            app.STOPButton = uibutton(app.UIFigure, 'push');
            app.STOPButton.ButtonPushedFcn = createCallbackFcn(app, @STOPButtonPushed, true);
            app.STOPButton.Text = 'STOP';
            app.STARTButton = uibutton(app.UIFigure, 'push');
            app.STARTButton.ButtonPushedFcn = createCallbackFcn(app, @startButton, true);
            app.STARTButton.Text = 'START';
            app.movingButton = uibutton(app.UIFigure, 'push');
            app.movingButton.ButtonPushedFcn = createCallbackFcn(app, @movingButtonPushed, true);
            app.movingButton.Text = 'moving';
        end
    end

    methods (Access = public)

        % Construct app
        function app = ctrlpanel

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end